classdef Group8_BatteryThermal_Mechanic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout2                     matlab.ui.container.GridLayout
        CoolantPumpCharacteristicPanel  matlab.ui.container.Panel
        ActuatorGraph                   matlab.ui.control.UIAxes
        EVBatteryThermalPlantPanel      matlab.ui.container.Panel
        SystemView                      matlab.ui.control.UIAxes
        NTCCharacteristicCurvePanel     matlab.ui.container.Panel
        SensorGraph                     matlab.ui.control.UIAxes
        DataAcquisitionDAQOutputsPanel  matlab.ui.container.Panel
        ADCDropDown                     matlab.ui.control.DropDown
        MicrocontrollerADCDropDownLabel  matlab.ui.control.Label
        ResolutionField                 matlab.ui.control.NumericEditField
        ResolutionCLabel                matlab.ui.control.Label
        PowerField                      matlab.ui.control.NumericEditField
        PowerConsumptionWEditFieldLabel  matlab.ui.control.Label
        FlowField                       matlab.ui.control.NumericEditField
        SensitivitymVCLabel             matlab.ui.control.Label
        SensField                       matlab.ui.control.NumericEditField
        SensitivitymVCLabel_2           matlab.ui.control.Label
        PlantControlInputsPanel         matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        BatteryTempCKnob                matlab.ui.control.Knob
        CoolantPumpVKnob                matlab.ui.control.Knob
        BatteryTempCLabel               matlab.ui.control.Label
        CoolantPumpVKnobLabel           matlab.ui.control.Label
    end


    properties (Access = private)
        BatteryPatch
        PumpPatch
        PumpMotor
        PumpCap
        NTCLine
        OperatingPoint
        ChannelLine
        SensorMarker
        PumpLine
        PumpOperatingPoint

        % --- NEW: ANIMATION & OPTIMIZATION PROPERTIES ---
        FlowTimer
        ParticleMarker
        PipePathX
        PipePathY
        PipePathZ
        FlowProgress = 0;
        LastInteractionTime % Tracks the 0.8-second mechanical delay
        Last_Temp = -999;   % Stops the UI lag
        Last_Pump_V = -999;
    end

    methods (Access = private)

function updateSystem(app)
            % --- INPUTS ---
            Temp_C = app.BatteryTempCKnob.Value;
            Temp_K = Temp_C + 273.15;
            Pump_V = app.CoolantPumpVKnob.Value;

            % --- MODEL PARAMETERS ---
            R25 = 10000; Beta = 3950; R_fixed = 10000; V_ref = 5;

            % --- 1. SENSOR MATH ---
            R_thermistor = R25 * exp(Beta * (1/Temp_K - 1/298.15));
            V_sensor = V_ref * (R_thermistor / (R_thermistor + R_fixed));

            % --- 2. ACTUATOR MATH (WITH NEW 1.5V BUFFER ZONE) ---
            Deadband_V = 1.5;
            if Pump_V < Deadband_V
                FlowRate = 0;
                Power = 0;
            else
                FlowRate = 5 * (Pump_V - Deadband_V);
                Power = (Pump_V^2) / 50;
            end

            % --- 3. SENSITIVITY MATH ---
            T_step = Temp_K + 0.1;
            R_step = R25 * exp(Beta * (1/T_step - 1/298.15));
            V_step = V_ref * (R_step / (R_step + R_fixed));
            Sensitivity = (V_sensor - V_step) / 0.1 * 1000;

            % --- 4. RESOLUTION MATH ---
            adc_selection = app.ADCDropDown.Value;
            if strcmp(adc_selection, '10-bit (Low-Tier Sensor Node)')
                adc_steps = 1023;
            elseif strcmp(adc_selection, '12-bit (Standard EV ECU)')
                adc_steps = 4095;
            else
                adc_steps = 65535;
            end
            Min_Voltage_Step = (V_ref / adc_steps) * 1000;
            Temp_Resolution = abs(Min_Voltage_Step / Sensitivity);

            % --- 5. OPTIMIZED GRAPH UPDATE ---
            app.OperatingPoint.XData = Temp_C;
            app.OperatingPoint.YData = R_thermistor;
            app.PumpOperatingPoint.XData = Pump_V;
            app.PumpOperatingPoint.YData = FlowRate;

            % --- 6. UPDATE METRICS ---
            app.SensField.Value = Sensitivity;
            app.FlowField.Value = FlowRate;
            app.PowerField.Value = Power;
            app.ResolutionField.Value = Temp_Resolution;

            % --- 7. UPDATE 3D VISUALIZATION ---
            color_temp = max(0, min(100, Temp_C));
            red_intensity = color_temp / 100;
            blue_intensity = 1 - red_intensity;
            
            % If 3D components exist, update their colors
            if ~isempty(app.BatteryPatch)
                app.BatteryPatch.FaceColor = [red_intensity, 0, blue_intensity];
                app.PumpPatch.FaceAlpha = min(1, 0.3 + (Pump_V/20));
                pipe_color_intensity = 1 - (Pump_V / 12);
                app.ChannelLine.Color = [0, pipe_color_intensity, 1];
            end
        end

        % --- NEW: DYNAMIC PHYSICS & LATENCY ENGINE ---
        function animateFlow(app)
            if ~isvalid(app) || ~isvalid(app.SystemView)
                return;
            end

            Pump_V = app.CoolantPumpVKnob.Value;
            Current_Temp = app.BatteryTempCKnob.Value;
            Coolant_Temp = 25;
            
            Deadband_V = 1.5;
            if Pump_V < Deadband_V
                FlowRate = 0;
            else
                FlowRate = 5 * (Pump_V - Deadband_V);
            end

            % Check if the user is interacting to apply the REAL-WORLD DELAY (0.8 seconds)
            is_delayed = toc(app.LastInteractionTime) < 0.8;

            % 1. Adiabatic Thermal Dynamics (Only runs if delay is over and fluid is moving)
            if FlowRate > 0 && ~is_delayed
                Convection_Coefficient = 0.0005;
                delta_T = -Convection_Coefficient * FlowRate * (Current_Temp - Coolant_Temp);
                Current_Temp = Current_Temp + delta_T;

                if abs(delta_T) > 0.01
                    app.BatteryTempCKnob.Value = Current_Temp;
                end
            end

            % 2. DECOUPLED UI RENDERING (Fixes the knob drag lag)
            if abs(Current_Temp - app.Last_Temp) > 0.1 || abs(Pump_V - app.Last_Pump_V) > 0.1
                updateSystem(app);
                app.Last_Temp = Current_Temp;
                app.Last_Pump_V = Pump_V;
            end

            % 3. Fluid Droplet Animation
            if FlowRate > 0 && ~is_delayed
                app.FlowProgress = app.FlowProgress + (FlowRate * 0.15);
                app.FlowProgress = mod(app.FlowProgress, 100);

                num_particles = min(12, length(app.PipePathX));
                if num_particles > 0
                    idx = zeros(1, num_particles);
                    for i = 1:num_particles
                        offset = mod(app.FlowProgress + (i-1)*(100/num_particles), 100);
                        idx(i) = max(1, round((offset/100) * length(app.PipePathX)));
                    end
                    app.ParticleMarker.XData = app.PipePathX(idx);
                    app.ParticleMarker.YData = app.PipePathY(idx);
                    app.ParticleMarker.ZData = app.PipePathZ(idx);
                    app.ParticleMarker.Visible = 'on';
                end
            else
                if ~isempty(app.ParticleMarker)
                    app.ParticleMarker.Visible = 'off';
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % --- 0. WIPE CANVAS & APPLY STABLE CAMERA LIMITS ---
            cla(app.SystemView, 'reset'); 
            
            xlim(app.SystemView, [-2.5, 2.5]);
            ylim(app.SystemView, [-3.0, 3.0]); 
            zlim(app.SystemView, [-0.5, 2.0]);
            
            % --- 1. SETUP SIMSCAPE-STYLE X-RAY RENDER ---
            axis(app.SystemView, 'off');
            grid(app.SystemView, 'off');
            app.SystemView.Color = 'none'; 
            
            hold(app.SystemView, 'on');

            % 1a. The "X-Ray" Car Shell
            try
                car_mesh = stlread('body.stl');
                [reduced_faces, reduced_vertices] = reducepatch(car_mesh.ConnectivityList, car_mesh.Points, 0.05);
                
                min_v = min(reduced_vertices); max_v = max(reduced_vertices);
                center_v = (max_v + min_v) / 2;
                reduced_vertices(:,1) = reduced_vertices(:,1) - center_v(1);
                reduced_vertices(:,2) = reduced_vertices(:,2) - center_v(2);
                reduced_vertices(:,3) = reduced_vertices(:,3) - min_v(3); 
                
                ScaleFactor = 4.4 / max(max_v - min_v);
                reduced_vertices = reduced_vertices * ScaleFactor;
                reduced_vertices(:,2) = reduced_vertices(:,2) * 1.3; 
                reduced_vertices(:,3) = reduced_vertices(:,3) - 0.15;

                patch(app.SystemView, 'Faces', reduced_faces, 'Vertices', reduced_vertices, ...
                    'FaceColor', [0.2 0.4 0.7], 'FaceAlpha', 0.20, 'EdgeColor', 'none', 'FaceLighting', 'gouraud');
            catch
                % Fallback Hologram Sedan
                VL = [-2.2 -0.95 0; -2.1 -0.95 0.3; -1.2 -0.95 0.35; -0.5 -0.95 0.95; 0.3 -0.95 0.95; 1.0 -0.95 0.5; 2.1 -0.95 0.35; 2.2 -0.95 0.15];
                VR = [-2.2 0.95 0; -2.1 0.95 0.3; -1.2 0.95 0.35; -0.5 0.95 0.95; 0.3 0.95 0.95; 1.0 0.95 0.5; 2.1 0.95 0.35; 2.2 0.95 0.15];
                patch(app.SystemView, 'Vertices', [VL; VR], 'Faces', [1:8; 9:16; 1 2 10 9 NaN NaN NaN NaN; 2 3 11 10 NaN NaN NaN NaN; 3 4 12 11 NaN NaN NaN NaN; 4 5 13 12 NaN NaN NaN NaN; 5 6 14 13 NaN NaN NaN NaN; 6 7 15 14 NaN NaN NaN NaN; 7 8 16 15 NaN NaN NaN NaN], 'FaceColor', [0.2 0.4 0.7], 'FaceAlpha', 0.2, 'EdgeColor', 'none', 'FaceLighting', 'gouraud');
            end

            % 1b. Build the Hyper-Realistic VERTICAL Cylindrical Cell Array
            % Corrected orientation: Long in Y (length), Narrow in X (width)
            v_batt = []; f_batt = [];
            x_rows = [-0.45, -0.27, -0.09, 0.09, 0.27, 0.45]; % 6 columns across car width
            y_cols = linspace(-1.2, 0.8, 15);                 % 15 rows along car length
            
            n = 8; r = 0.07; h = 0.25; 
            theta = linspace(0, 2*pi, n+1)'; theta(end) = [];
            xb = r*cos(theta); yb = r*sin(theta);
            v_temp = [xb, yb, zeros(n,1); xb, yb, h*ones(n,1)];
            
            f_temp = NaN(n+2, n);
            f_temp(1, 1:n) = 1:n;           
            f_temp(2, 1:n) = (n+1):(2*n);   
            for i=1:n
                f_temp(i+2, 1:4) = [i, mod(i,n)+1, mod(i,n)+1+n, i+n]; 
            end

            idx = 0;
            for xr = x_rows
                for yc = y_cols
                    v_batt = [v_batt; v_temp + [xr, yc, 0]];
                    f_batt = [f_batt; f_temp + 2*n*idx];
                    idx = idx + 1;
                end
            end
            
            app.BatteryPatch = patch(app.SystemView, 'Vertices', v_batt, 'Faces', f_batt, ...
                'FaceColor', [0.1, 0.35, 0.85], 'EdgeColor', 'none', 'FaceLighting', 'gouraud');
            
            % 1c. Build the Perfected Centrifugal BLDC Pump (3-Part Design)
            % Pump Volute Base (Wide, Red)
            [vx, vy, vz] = cylinder(0.18, 30);
            vz = vz * 0.15; vy = vy + 1.4;
            app.PumpPatch = surf(app.SystemView, vx, vy, vz, 'FaceColor', [0.8, 0.2, 0.2], 'EdgeColor', 'none', 'FaceLighting', 'gouraud');
            
            % Motor Housing (Tall, Metallic Silver)
            [mx, my, mz] = cylinder(0.12, 30);
            mz = mz * 0.3 + 0.15; my = my + 1.4;
            app.PumpMotor = surf(app.SystemView, mx, my, mz, 'FaceColor', [0.7, 0.7, 0.75], 'EdgeColor', 'none', 'FaceLighting', 'gouraud');

            % Motor Cap (Black)
            app.PumpCap = patch(app.SystemView, mx(2,:), my(2,:), mz(2,:), [0.15, 0.15, 0.15], 'EdgeColor', 'none', 'FaceLighting', 'gouraud');

            % 1d. The Corrugated Serpentine Ribbon (Longitudinal Weaving)
            % Paths weave along the Y-axis between the X_rows
            px = [0,   -0.36, -0.36, -0.18, -0.18,  0.18,  0.18,  0.36, 0.36, 0];
            py = [1.4,  1.0,  -1.35, -1.35,  1.0,   1.0,  -1.35, -1.35, 1.0,  1.4];
            pz = [0.12, 0.12,  0.12,  0.12,  0.12,  0.12,  0.12,  0.12, 0.12, 0.12];
            
            pt_x = []; pt_y = []; pt_z = [];
            cell_spacing = (0.8 - (-1.2)) / 14; 
            wave_freq = 2*pi / cell_spacing; 
            
            for i=1:length(px)-1
                dist = sqrt((px(i+1)-px(i))^2 + (py(i+1)-py(i))^2);
                num_pts = max(15, round(dist * 80)); 
                seg_x = linspace(px(i), px(i+1), num_pts);
                seg_y = linspace(py(i), py(i+1), num_pts);
                
                % THE MAGIC: Apply sine wave to X-coordinates for vertical channels
                if abs(py(i+1) - py(i)) > 1.0
                    seg_x = seg_x + 0.05 * sin((seg_y - (-1.2)) * wave_freq);
                end
                
                pt_x = [pt_x, seg_x];
                pt_y = [pt_y, seg_y];
                pt_z = [pt_z, linspace(pz(i), pz(i+1), num_pts)]; 
            end
            app.PipePathX = pt_x; app.PipePathY = pt_y; app.PipePathZ = pt_z;

            app.ChannelLine = plot3(app.SystemView, pt_x, pt_y, pt_z, 'Color', [0, 0.8, 1], 'LineWidth', 4);
            
            app.ParticleMarker = plot3(app.SystemView, pt_x(1:12), pt_y(1:12), pt_z(1:12), 'o', ...
                'MarkerSize', 5, 'MarkerFaceColor', 'w', 'MarkerEdgeColor', 'none', 'Visible', 'off');
                
            % 1e. Build the 3D NTC Thermistor Sensor (Realistic Bead + Wires)
            [sx, sy, sz] = sphere(15);
            sx = sx * 0.045 + 0.0; sy = sy * 0.045 - 0.2; sz = sz * 0.045 + 0.26;
            app.SensorMarker = surf(app.SystemView, sx, sy, sz, 'FaceColor', [1, 0.8, 0], 'EdgeColor', 'none', 'FaceLighting', 'gouraud');
            
            % Draw physical signal wires bridging the sensor to the battery
            plot3(app.SystemView, [0.0, -0.03], [-0.2, -0.2], [0.26, 0.23], 'k-', 'LineWidth', 1.5);
            plot3(app.SystemView, [0.0, 0.03], [-0.2, -0.2], [0.26, 0.23], 'k-', 'LineWidth', 1.5);

            % 1f. ADD THE INTERACTIVE LEGEND
            lgd = legend(app.SystemView, [app.BatteryPatch, app.PumpPatch, app.ChannelLine, app.SensorMarker], ...
                '18650 Li-ion Cell Array', 'BLDC Coolant Pump', 'Corrugated Cooling Ribbon', 'NTC Thermistor Bead');
            lgd.Location = 'northeast'; lgd.Color = [0.95 0.95 0.95]; lgd.FontSize = 10;
            
            hold(app.SystemView, 'off');

            % --- THE LIGHTING & CAMERA ENGINE ---
            light(app.SystemView, 'Position', [-1 -1 1], 'Style', 'infinite');
            light(app.SystemView, 'Position', [1 1 0.5], 'Style', 'local');
            material(app.SystemView, 'dull'); 
            
            % Set ideal isometric viewing angle
            view(app.SystemView, [-30 55]); 
            
            % --- 2. DRAW THE 2D SENSOR GRAPH ONCE ---
            R25 = 10000; Beta = 3950; R_fixed = 10000; V_ref = 5;
            T_range = -20:1:120; T_range_K = T_range + 273.15;
            R_curve = R25 .* exp(Beta .* (1./T_range_K - 1/298.15));

            hold(app.SensorGraph, 'on');
            app.NTCLine = plot(app.SensorGraph, T_range, R_curve, 'b-', 'LineWidth', 2);
            app.OperatingPoint = plot(app.SensorGraph, 25, 10000, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');
            hold(app.SensorGraph, 'off');
            xlim(app.SensorGraph, [-20, 120]); title(app.SensorGraph, "NTC Characteristic Curve");
            xlabel(app.SensorGraph, "Temp (°C)"); ylabel(app.SensorGraph, "Resistance (Ohms)");

            % --- 3. DRAW THE ACTUATOR GRAPH ONCE ---
            V_range = 0:0.1:12;
            Deadband_V = 1.5;
            F_curve = zeros(size(V_range));
            idx_active = V_range >= Deadband_V;
            F_curve(idx_active) = 5 .* (V_range(idx_active) - Deadband_V);

            hold(app.ActuatorGraph, 'on');
            app.PumpLine = plot(app.ActuatorGraph, V_range, F_curve, 'b-', 'LineWidth', 2);
            app.PumpOperatingPoint = plot(app.ActuatorGraph, 0, 0, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');
            hold(app.ActuatorGraph, 'off');
            xlim(app.ActuatorGraph, [0, 12]); ylim(app.ActuatorGraph, [0, 55]);
            title(app.ActuatorGraph, "Coolant Pump Characteristic");
            xlabel(app.ActuatorGraph, "Voltage (V)"); ylabel(app.ActuatorGraph, "Flow Rate (L/min)");
            drawnow;

            app.LastInteractionTime = tic; % Initialize the latency timer
            updateSystem(app);

            % --- 4. START THE FLUID ANIMATION TIMER ---
            app.FlowTimer = timer('ExecutionMode', 'fixedSpacing', 'Period', 0.05, ...
                'TimerFcn', @(~,~) app.animateFlow());
            start(app.FlowTimer);
        end

        % Value changing function: BatteryTempCKnob
        function BatteryTempCKnobValueChanging(app, event)
            app.LastInteractionTime = tic; % Reset latency delay
            app.BatteryTempCKnob.Value = event.Value;
        end

        % Callback function: ADCDropDown, CoolantPumpVKnob
        function CoolantPumpVKnobValueChanging(app, event)
            app.LastInteractionTime = tic; % Reset latency delay
            app.CoolantPumpVKnob.Value = event.Value;
        end

        % Callback function: not associated with a component
        function ADCDropDownValueChanged(app, event)
            app.LastInteractionTime = tic;
            updateSystem(app);
        end

        % Value changed function: BatteryTempCKnob
        function BatteryTempCKnobValueChanged(app, event)
            app.LastInteractionTime = tic;
            app.BatteryTempCKnob.Value = event.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % 1. Safely stop and delete the animation timer to prevent memory leaks
            if ~isempty(app.FlowTimer) && isvalid(app.FlowTimer)
                stop(app.FlowTimer);
                delete(app.FlowTimer);
            end

            % 2. Execute the standard delete command to close the window
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.PlantControlInputsPanel = uipanel(app.GridLayout2);
            app.PlantControlInputsPanel.Title = 'Plant Control Inputs';
            app.GridLayout = uigridlayout(app.PlantControlInputsPanel);
            app.CoolantPumpVKnobLabel = uilabel(app.GridLayout);
            app.CoolantPumpVKnobLabel.Text = 'Coolant Pump (V)';
            app.BatteryTempCLabel = uilabel(app.GridLayout);
            app.BatteryTempCLabel.Text = 'Battery Temp (°C)';
            app.CoolantPumpVKnob = uiknob(app.GridLayout, 'continuous');
            app.CoolantPumpVKnob.Limits = [0 12];
            app.CoolantPumpVKnob.ValueChangingFcn = createCallbackFcn(app, @CoolantPumpVKnobValueChanging, true);
            app.BatteryTempCKnob = uiknob(app.GridLayout, 'continuous');
            app.BatteryTempCKnob.Limits = [-20 120];
            app.BatteryTempCKnob.ValueChangedFcn = createCallbackFcn(app, @BatteryTempCKnobValueChanged, true);
            app.BatteryTempCKnob.ValueChangingFcn = createCallbackFcn(app, @BatteryTempCKnobValueChanging, true);
            app.DataAcquisitionDAQOutputsPanel = uipanel(app.GridLayout2);
            app.DataAcquisitionDAQOutputsPanel.Title = 'Data Acquisition (DAQ) Outputs';
            app.SensitivitymVCLabel_2 = uilabel(app.DataAcquisitionDAQOutputsPanel);
            app.SensitivitymVCLabel_2.Text = 'Sensitivity (mV/°C)';
            app.SensField = uieditfield(app.DataAcquisitionDAQOutputsPanel, 'numeric');
            app.SensField.Editable = 'off';
            app.SensitivitymVCLabel = uilabel(app.DataAcquisitionDAQOutputsPanel);
            app.SensitivitymVCLabel.Text = 'Flow Rate (L/min)';
            app.FlowField = uieditfield(app.DataAcquisitionDAQOutputsPanel, 'numeric');
            app.FlowField.Editable = 'off';
            app.PowerConsumptionWEditFieldLabel = uilabel(app.DataAcquisitionDAQOutputsPanel);
            app.PowerConsumptionWEditFieldLabel.Text = 'Power Consumption (W)';
            app.PowerField = uieditfield(app.DataAcquisitionDAQOutputsPanel, 'numeric');
            app.PowerField.Editable = 'off';
            app.ResolutionCLabel = uilabel(app.DataAcquisitionDAQOutputsPanel);
            app.ResolutionCLabel.Text = 'Resolution (°C)';
            app.ResolutionField = uieditfield(app.DataAcquisitionDAQOutputsPanel, 'numeric');
            app.ResolutionField.Editable = 'off';
            app.MicrocontrollerADCDropDownLabel = uilabel(app.DataAcquisitionDAQOutputsPanel);
            app.MicrocontrollerADCDropDownLabel.Text = 'Microcontroller ADC';
            app.ADCDropDown = uidropdown(app.DataAcquisitionDAQOutputsPanel);
            app.ADCDropDown.Items = {'10-bit (Low-Tier Sensor Node)', '12-bit (Standard EV ECU)', '16-bit (Advanced BMS Controller)'};
            app.ADCDropDown.ValueChangedFcn = createCallbackFcn(app, @CoolantPumpVKnobValueChanging, true);
            app.ADCDropDown.Value = '10-bit (Low-Tier Sensor Node)';
            app.NTCCharacteristicCurvePanel = uipanel(app.GridLayout2);
            app.NTCCharacteristicCurvePanel.Title = 'NTC Characteristic Curve';
            app.SensorGraph = uiaxes(app.NTCCharacteristicCurvePanel);
            title(app.SensorGraph, 'SensorGraph')
            xlabel(app.SensorGraph, 'X')
            ylabel(app.SensorGraph, 'Y')
            zlabel(app.SensorGraph, 'Z')
            app.EVBatteryThermalPlantPanel = uipanel(app.GridLayout2);
            app.EVBatteryThermalPlantPanel.Title = 'EV Battery Thermal Plant';
            app.SystemView = uiaxes(app.EVBatteryThermalPlantPanel);
            title(app.SystemView, 'SystemView')
            xlabel(app.SystemView, 'X')
            ylabel(app.SystemView, 'Y')
            zlabel(app.SystemView, 'Z')
            app.CoolantPumpCharacteristicPanel = uipanel(app.GridLayout2);
            app.CoolantPumpCharacteristicPanel.Title = 'Coolant Pump Characteristic';
            app.ActuatorGraph = uiaxes(app.CoolantPumpCharacteristicPanel);
            title(app.ActuatorGraph, 'ActuatorGraph')
            xlabel(app.ActuatorGraph, 'X')
            ylabel(app.ActuatorGraph, 'Y')
            zlabel(app.ActuatorGraph, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Group8_BatteryThermal_Mechanic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end